classdef APP_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label3                         matlab.ui.control.Label
        PulsesperSecondLabel           matlab.ui.control.Label
        DelayLabel                     matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        CalculateButton                matlab.ui.control.Button
        PulsesperSecondEditField       matlab.ui.control.NumericEditField
        PulsesperSecondEditFieldLabel  matlab.ui.control.Label
        DistanceinkilometersEditField  matlab.ui.control.NumericEditField
        DistanceinkilometersEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            c = 3e8;    % speed of light in meters per second 

            d_km = app.DistanceinkilometersEditField.Value;  % distance in km 
            pulseRate = app.PulsesperSecondEditField.Value;

            d_m = d_km * 1e3;    % convert distance to meters 
            t_sec = 2 * d_m / c; % two-way relay 
            t_min = t_sec / 60;  

            Pulses = pulseRate * t_sec; 

            app.DelayLabel.Text = sprintf('Two-way delay: %.2f minutes', t_min);
            app.PulsesperSecondLabel.Text = sprintf('Pulses in flight: %.0f', Pulses);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DistanceinkilometersEditFieldLabel = uilabel(app.UIFigure);
            app.DistanceinkilometersEditFieldLabel.Text = 'Distance in kilometers';
            app.DistanceinkilometersEditField = uieditfield(app.UIFigure, 'numeric');
            app.PulsesperSecondEditFieldLabel = uilabel(app.UIFigure);
            app.PulsesperSecondEditFieldLabel.Text = 'Pulses per Second';
            app.PulsesperSecondEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'jupiter.png');
            app.DelayLabel = uilabel(app.UIFigure);
            app.DelayLabel.Text = 'Delay';
            app.PulsesperSecondLabel = uilabel(app.UIFigure);
            app.PulsesperSecondLabel.Text = 'Pulses per Second';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = {'Radar is routinely used as one means of measuring the distance to objects in space. '; 'For example, it has been used to calculate the orbital parameters and rate of rotation of the planet Jupiter'; 'The distance from Earth to Jupiter varies from 588.5*10^6 to 968.1*10^6 km. '; 'What are the minimum and maximum time delays in minutes from the time a pulse is transmmitted in '; 'the direction of Jupiter until the time echo is received? If pulses are thransmitted at a rate of 100 pulses'; 'per second, how many pulses are in flight, either on their way to Jupiter or back again at any given distant?'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end