classdef Simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Image                        matlab.ui.control.Image
        TelemetryLabel               matlab.ui.control.Label
        StatusLabel                  matlab.ui.control.Label
        ResolutionStateLabel         matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        TargetInfoLabel              matlab.ui.control.Label
        ProcessingGainLabel          matlab.ui.control.Label
        SNRAfterLabel                matlab.ui.control.Label
        SNRBeforeLabel               matlab.ui.control.Label
        ResetButton                  matlab.ui.control.Button
        NewNoiseButton               matlab.ui.control.Button
        TargetModeButtonGroup        matlab.ui.container.ButtonGroup
        UnresolvablePairButton       matlab.ui.control.RadioButton
        ResolvablePairButton         matlab.ui.control.RadioButton
        SingleTargetButton           matlab.ui.control.RadioButton
        GuardTimeSpinner             matlab.ui.control.Spinner
        ReceiveGuardTimeLabel        matlab.ui.control.Label
        SNRSpinner                   matlab.ui.control.Spinner
        SNRdBSpinnerLabel            matlab.ui.control.Label
        SampleRateSpinner            matlab.ui.control.Spinner
        SamplerateMHzSpinnerLabel    matlab.ui.control.Label
        PulseWidthSpinner            matlab.ui.control.Spinner
        PulseWidthMicrosecondsLabel  matlab.ui.control.Label
        BandwidthSpinner             matlab.ui.control.Spinner
        BandwidthMhzSpinnerLabel     matlab.ui.control.Label
        TimeBandwidthLabel           matlab.ui.control.Label
        RangeResolutionLabel         matlab.ui.control.Label
        RunButton                    matlab.ui.control.Button
        RangeAxes                    matlab.ui.control.UIAxes
        WaveformAxes                 matlab.ui.control.UIAxes
    end


    methods (Access = private)
        
        function summary = makeTargetSummary(app, ranges, gainsdB)
            if numel(ranges) == 1  
                summary = sprintf('Target A: %.3f km, return %.1f dB',ranges(1) /1000, gainsdB(1));
            else
                summary = sprintf(['Target A: %.3f, %.1f dB\n','Target B: %.3f km, %.1f dB\n', 'Separation: %.1f m'], ranges(1)/1000, gainsdB(1), ranges(2)/1000, gainsdB(2), abs(diff(ranges)));
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: RunButton, WaveformAxes
        function SimulateButtonPushed(app, event)

           % Model constants and inputs
            c = 299792458;                                 % m/s - no toolbox dependency
            B = app.BandwidthSpinner.Value * 1e6;          % Hz
            T = app.PulseWidthSpinner.Value * 1e-6;        % s
            Fs = app.SampleRateSpinner.Value * 1e6;        % Hz
            inputSNRdB = app.SNRSpinner.Value;
            guardTime = app.GuardTimeSpinner.Value * 1e-6; % s

            if B <= 0 || T <= 0 || Fs <= 0
                uialert(app.UIFigure, 'Bandwidth, pulse width, and sample rate must be positive.', ...
                    'Invalid Parameters');
                return
            end
            if Fs < 2 * B
                uialert(app.UIFigure, ...
                    'Use a sample rate at least twice the bandwidth to avoid aliasing.', ...
                    'Sample-Rate Warning');
                return
            end

            N = round(T * Fs);
            if N < 8
                uialert(app.UIFigure, ...
                    'Increase pulse width or sample rate so the LFM pulse has at least eight samples.', ...
                    'Too Few Samples');
                return
            end

            % Generate the sampled LFM explicitly from its exponential phase.
            t = (0:N-1) / Fs;
            chirpRate = B / T;
            phase = 2*pi * (-B/2 * t + chirpRate/2 * t.^2);
            tx = exp(1j * phase);

            rangeResolution = c / (2 * B);
            timeBandwidth = B * T;

            % Scenario presets scale with resolution so that changing B remains meaningful.
            selectedMode = app.TargetModeButtonGroup.SelectedObject.Text;
            targetRanges = 3000;                   % m, Target A
            targetGainsdB = 0;
            targetPhases = 0.24;
            if app.TargetModeButtonGroup.SelectedObject == app.ResolvablePairButton
                targetRanges = [3000, 3000 + 2 * rangeResolution];
                targetGainsdB = [0, -4];
                targetPhases = [0.24, 1.55];
            elseif app.TargetModeButtonGroup.SelectedObject == app.UnresolvablePairButton
                targetRanges = [3000, 3000 + 0.50 * rangeResolution];
                targetGainsdB = [0, -4];
                targetPhases = [0.24, 1.55];
            end
            % Construct delayed target echoes inside a receive record.
            maxRoundTripDelay = max(2 * targetRanges / c);
            Nreceive = ceil((guardTime + maxRoundTripDelay + T + 5e-6) * Fs);
            cleanReceive = complex(zeros(1, Nreceive));
            primaryEcho = complex(zeros(1, Nreceive));
            startSamples = zeros(1, numel(targetRanges));

            for index = 1:numel(targetRanges)
                startSample = round((guardTime + 2 * targetRanges(index) / c) * Fs) + 1;
                stopSample = startSample + N - 1;
                startSamples(index) = startSample;
                amplitude = 10^(targetGainsdB(index) / 20);
                echo = amplitude * exp(1j * targetPhases(index)) * tx;
                cleanReceive(startSample:stopSample) = cleanReceive(startSample:stopSample) + echo;
                if index == 1
                    primaryEcho(startSample:stopSample) = echo;
                end
            end
             % Add complex AWGN.  The displayed input SNR is for an active Target-A sample.
            noisePower = 1 / (10^(inputSNRdB / 10));
            noise = sqrt(noisePower / 2) * (randn(1, Nreceive) + 1j * randn(1, Nreceive));
            received = cleanReceive + noise;

            % Form the matched filter manually: h[n] = conj(x[N-1-n]).
            matchedFilter = conj(fliplr(tx));
            matchedOutput = conv(received, matchedFilter);
            cleanMatchedOutput = conv(cleanReceive, matchedFilter);
            noiseMatchedOutput = conv(noise, matchedFilter);

            targetAIndices = startSamples(1):(startSamples(1) + N - 1);
            snrBefore = 10 * log10( ...
                mean(abs(primaryEcho(targetAIndices)).^2) / mean(abs(noise(targetAIndices)).^2));
            validNoiseIndices = (N + 1):Nreceive;
            snrAfter = 10 * log10( ...
                max(abs(cleanMatchedOutput).^2) / mean(abs(noiseMatchedOutput(validNoiseIndices)).^2));

            % A matched-filter peak at sample n has delay (n - (N-1))/Fs.
            matchedDelay = ((0:numel(matchedOutput)-1) - (N-1)) / Fs - guardTime;
            rangeMeters = c * matchedDelay / 2;
            rangeLimit = max(4000, max(targetRanges) + 1000);
            visible = rangeMeters >= 0 & rangeMeters <= rangeLimit;
            reference = max(abs(matchedOutput(visible)));
            matcheddB = 20 * log10(max(abs(matchedOutput), eps) / reference);

            % Update numerical readouts.
            app.TimeBandwidthLabel.Text = sprintf('Time-bandwidth product: %.0f', timeBandwidth);
            app.RangeResolutionLabel.Text = sprintf('Range resolution: %.1f m', rangeResolution);
            app.SNRBeforeLabel.Text = sprintf('Before filter: %.1f dB', snrBefore);
            app.SNRAfterLabel.Text = sprintf('After filter: %.1f dB', snrAfter);
            app.ProcessingGainLabel.Text = sprintf('Processing gain: %+.1f dB', snrAfter - snrBefore);
            app.TargetInfoLabel.Text = app.makeTargetSummary(targetRanges, targetGainsdB);

            if isscalar(targetRanges)
                app.ResolutionStateLabel.Text = 'One echo expected. Select a pair scenario to compare resolution.';
                app.ResolutionStateLabel.FontColor = [0.55 0.69 0.75];
            else
                separation = abs(diff(targetRanges));
                if separation >= rangeResolution
                    app.ResolutionStateLabel.Text = sprintf('Resolvable: separation %.1f m >= Delta R %.1f m', ...
                        separation, rangeResolution);
                    app.ResolutionStateLabel.FontColor = [0.68 0.92 0.47];
                else
                    app.ResolutionStateLabel.Text = sprintf('Unresolvable: separation %.1f m < Delta R %.1f m', ...
                        separation, rangeResolution);
                    app.ResolutionStateLabel.FontColor = [0.96 0.74 0.39];
                end
            end

            app.StatusLabel.Text = sprintf('SIMULATED  |  %s  |  %d pulse samples', upper(selectedMode), N);
            app.TelemetryLabel.Text = sprintf( ...
                'x(t) uses exp(j phi)  ->  delayed target echoes + complex AWGN  ->  h[n] = conj(x[N-1-n])');

             % Plot I and Q of the transmit waveform.
            cla(app.WaveformAxes);
            plot(app.WaveformAxes, t * 1e6, real(tx), 'Color', [0.13 0.84 0.87], 'LineWidth', 1.2);
            hold(app.WaveformAxes, 'on');
            plot(app.WaveformAxes, t * 1e6, imag(tx), 'Color', [0.66 0.57 1.00], 'LineWidth', 1.1);
            hold(app.WaveformAxes, 'off');
            title(app.WaveformAxes, 'Transmit view - complex LFM waveform');
            xlabel(app.WaveformAxes, 'Time (microseconds)');
            ylabel(app.WaveformAxes, 'Amplitude');
            legend(app.WaveformAxes, {'I / real', 'Q / imaginary'}, 'Location', 'northeast');
            grid(app.WaveformAxes, 'on');
            ylim(app.WaveformAxes, [-1.15 1.15]);

            % Plot the actual matched-filter range profile, not the transmit waveform.
            cla(app.RangeAxes);
            plot(app.RangeAxes, rangeMeters(visible) / 1000, matcheddB(visible), ...
                'Color', [0.13 0.84 0.87], 'LineWidth', 1.3);
            hold(app.RangeAxes, 'on');
            for index = 1:numel(targetRanges)
                if index == 1
                    lineColor = [0.13 0.84 0.87];
                    label = 'Target A';
                else
                    lineColor = [0.96 0.74 0.39];
                    label = 'Target B';
                end
                xline(app.RangeAxes, targetRanges(index) / 1000, '--', label, ...
                    'Color', lineColor, 'LineWidth', 1.0, 'LabelVerticalAlignment', 'bottom');
            end
            hold(app.RangeAxes, 'off');
            title(app.RangeAxes, 'Receive / process view - matched-filter range profile');
            xlabel(app.RangeAxes, 'Range (km)');
            ylabel(app.RangeAxes, 'Normalized magnitude (dB)');
            grid(app.RangeAxes, 'on');
            xlim(app.RangeAxes, [0 rangeLimit / 1000]);
            ylim(app.RangeAxes, [-55 5]);
            drawnow limitrate


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WaveformAxes = uiaxes(app.UIFigure);
            title(app.WaveformAxes, 'Title')
            xlabel(app.WaveformAxes, 'X')
            ylabel(app.WaveformAxes, 'Y')
            zlabel(app.WaveformAxes, 'Z')
            app.WaveformAxes.ButtonDownFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.RangeAxes = uiaxes(app.UIFigure);
            title(app.RangeAxes, 'Title')
            xlabel(app.RangeAxes, 'X')
            ylabel(app.RangeAxes, 'Y')
            zlabel(app.RangeAxes, 'Z')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.RunButton.Text = 'Simulate';
            app.RangeResolutionLabel = uilabel(app.UIFigure);
            app.RangeResolutionLabel.Text = 'Range Resolution = ';
            app.TimeBandwidthLabel = uilabel(app.UIFigure);
            app.TimeBandwidthLabel.Text = 'Time Bandwidth = ';
            app.BandwidthMhzSpinnerLabel = uilabel(app.UIFigure);
            app.BandwidthMhzSpinnerLabel.Text = 'Bandwidth (Mhz)';
            app.BandwidthSpinner = uispinner(app.UIFigure);
            app.BandwidthSpinner.Limits = [0 500];
            app.PulseWidthMicrosecondsLabel = uilabel(app.UIFigure);
            app.PulseWidthMicrosecondsLabel.Text = {'Pulse Width'; ' (Microseconds)'};
            app.PulseWidthSpinner = uispinner(app.UIFigure);
            app.PulseWidthSpinner.Limits = [0 500];
            app.SamplerateMHzSpinnerLabel = uilabel(app.UIFigure);
            app.SamplerateMHzSpinnerLabel.Text = 'Sample rate  (MHz)';
            app.SampleRateSpinner = uispinner(app.UIFigure);
            app.SampleRateSpinner.Limits = [0 500];
            app.SNRdBSpinnerLabel = uilabel(app.UIFigure);
            app.SNRdBSpinnerLabel.Text = 'SNR (dB)';
            app.SNRSpinner = uispinner(app.UIFigure);
            app.ReceiveGuardTimeLabel = uilabel(app.UIFigure);
            app.ReceiveGuardTimeLabel.Text = {'Receive'; 'Guard Time'; '(Microseconds)'};
            app.GuardTimeSpinner = uispinner(app.UIFigure);
            app.GuardTimeSpinner.Limits = [0 500];
            app.TargetModeButtonGroup = uibuttongroup(app.UIFigure);
            app.TargetModeButtonGroup.Title = 'Target Mode';
            app.SingleTargetButton = uiradiobutton(app.TargetModeButtonGroup);
            app.SingleTargetButton.Text = 'Single Target';
            app.SingleTargetButton.Value = true;
            app.ResolvablePairButton = uiradiobutton(app.TargetModeButtonGroup);
            app.ResolvablePairButton.Text = 'Resolvable Pair';
            app.UnresolvablePairButton = uiradiobutton(app.TargetModeButtonGroup);
            app.UnresolvablePairButton.Text = 'Unresolvable Pair';
            app.NewNoiseButton = uibutton(app.UIFigure, 'push');
            app.NewNoiseButton.Text = 'New Noise';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Text = 'Reset';
            app.SNRBeforeLabel = uilabel(app.UIFigure);
            app.SNRBeforeLabel.Text = 'SNR Before =';
            app.SNRAfterLabel = uilabel(app.UIFigure);
            app.SNRAfterLabel.Text = 'SNR After = ';
            app.ProcessingGainLabel = uilabel(app.UIFigure);
            app.ProcessingGainLabel.Text = 'Processing Gain =';
            app.TargetInfoLabel = uilabel(app.UIFigure);
            app.TargetInfoLabel.Text = 'Target Info = ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ResolutionStateLabel = uilabel(app.UIFigure);
            app.ResolutionStateLabel.Text = 'Resolution State = ';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status = ';
            app.TelemetryLabel = uilabel(app.UIFigure);
            app.TelemetryLabel.Text = {'Telemetry = '; ''};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'radar3.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end